classdef Tugas_Kecil_2_IF4073 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ConvolutionTab                matlab.ui.container.Tab
        UkuranFilterSpinner           matlab.ui.control.Spinner
        UkuranFilterSpinnerLabel      matlab.ui.control.Label
        UsingBuiltinconvLabel         matlab.ui.control.Label
        Image_3                       matlab.ui.control.Image
        ConvolutedImageLabel          matlab.ui.control.Label
        Image_2                       matlab.ui.control.Image
        SourceImageLabel_2            matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        ChooseImageButton             matlab.ui.control.Button
        ImageSmoothingTab             matlab.ui.container.Tab
        UkuranFilterSpinner_2         matlab.ui.control.Spinner
        UkuranFilterSpinner_2Label    matlab.ui.control.Label
        TipeFilterDropDown            matlab.ui.control.DropDown
        TipeFilterDropDownLabel       matlab.ui.control.Label
        SmoothFilteredImageLabel      matlab.ui.control.Label
        Image_8                       matlab.ui.control.Image
        SourceImageLabel_4            matlab.ui.control.Label
        Image_7                       matlab.ui.control.Image
        ChooseImageButton_3           matlab.ui.control.Button
        LowPassFilteringTab           matlab.ui.container.Tab
        TipeFilterDropDown_3          matlab.ui.control.DropDown
        TipeFilterDropDown_3Label     matlab.ui.control.Label
        HighPassFilteredImageLabel_3  matlab.ui.control.Label
        Image_17                      matlab.ui.control.Image
        SourceImageLabel_8            matlab.ui.control.Label
        Image_16                      matlab.ui.control.Image
        ChooseImageButton_6           matlab.ui.control.Button
        HighPassFilteringTab_2        matlab.ui.container.Tab
        TipeFilterDropDown_2          matlab.ui.control.DropDown
        TipeFilterDropDown_2Label     matlab.ui.control.Label
        HighPassFilteredImageLabel_2  matlab.ui.control.Label
        Image_15                      matlab.ui.control.Image
        SourceImageLabel_7            matlab.ui.control.Label
        Image_14                      matlab.ui.control.Image
        ChooseImageButton_5           matlab.ui.control.Button
        BrigthenTab                   matlab.ui.container.Tab
        BrightnessSlider              matlab.ui.control.Slider
        BrightnessSliderLabel         matlab.ui.control.Label
        BrightenedImageLabel          matlab.ui.control.Label
        Image_10                      matlab.ui.control.Image
        SourceImageLabel_5            matlab.ui.control.Label
        Image_9                       matlab.ui.control.Image
        ChooseImageButton_4           matlab.ui.control.Button
        MedianFilterTab               matlab.ui.container.Tab
        MedianFilteredImageLabel      matlab.ui.control.Label
        Image_6                       matlab.ui.control.Image
        SaltandPepperNoisyImageLabel  matlab.ui.control.Label
        Image_5                       matlab.ui.control.Image
        SourceImageLabel_3            matlab.ui.control.Label
        Image_4                       matlab.ui.control.Image
        ChooseImageButton_2           matlab.ui.control.Button
        PeriodicDenoiseTab            matlab.ui.container.Tab
        ChooseImageDropDown           matlab.ui.control.DropDown
        ChooseImageDropDownLabel      matlab.ui.control.Label
        FilterResultinFrequencySpaceLabel  matlab.ui.control.Label
        Image_13                      matlab.ui.control.Image
        PeriodicDenoisedImageLabel    matlab.ui.control.Label
        Image_12                      matlab.ui.control.Image
        SourceImageLabel_6            matlab.ui.control.Label
        Image_11                      matlab.ui.control.Image
    end

    
    properties (Access = public)
        dir =  '..\images'; % Image Directory
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseImageButton
        function ChooseImageButtonPushed(app, event)
            [file,~] = uigetfile({'*.*';'*.png';'*.jpg'; '*.jpeg';},'Choose image',app.dir);
            src = fullfile(app.dir,file);
            app.Image.ImageSource = src;
            n = app.UkuranFilterSpinner.Value;
            filter = (1/(n*n) * ones(n));
            convimg = self_made_conv(imread(src),filter);
            builtin = uint8(convn(double(imread(src)), double(filter)));
            app.Image_2.ImageSource = convimg;
            app.Image_3.ImageSource = builtin;
        end

        % Value changed function: UkuranFilterSpinner
        function UkuranFilterSpinnerValueChanged(app, event)
            src = app.Image.ImageSource;
            n = app.UkuranFilterSpinner.Value;
            filter = (1/(n*n) * ones(n));
            convimg = self_made_conv(imread(src),filter);
            builtin = uint8(convn(double(imread(src)), double(filter)));
            app.Image_2.ImageSource = convimg;
            app.Image_3.ImageSource = builtin;
        end

        % Button pushed function: ChooseImageButton_2
        function ChooseImageButton_2Pushed(app, event)
            [file,~] = uigetfile({'*.*';'*.png';'*.jpg'; '*.jpeg';},'Choose image',app.dir);
            src = fullfile(app.dir,file);
            app.Image_4.ImageSource = src;
            noisyimg =  imnoise(imread(src), 'salt & pepper', 0.1);
            filtimg = self_made_medfilt(noisyimg,3);
            app.Image_5.ImageSource = noisyimg;
            app.Image_6.ImageSource = filtimg;
        end

        % Button pushed function: ChooseImageButton_4
        function ChooseImageButton_4Pushed(app, event)
            [file,~] = uigetfile({'*.*';'*.png';'*.jpg'; '*.jpeg';},'Choose image',app.dir);
            src = fullfile(app.dir,file);
            app.Image_9.ImageSource = src;
            scale = app.BrightnessSlider.Value;
            brightimg =  brighten(imread(src), scale);
            app.Image_10.ImageSource = brightimg;
        end

        % Button pushed function: ChooseImageButton_3
        function ChooseImageButton_3Pushed(app, event)
            
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            src = app.Image_9.ImageSource;
            scale = app.BrightnessSlider.Value;
            brightimg =  brighten(imread(src), scale);
            app.Image_10.ImageSource = brightimg;
        end

        % Value changed function: ChooseImageDropDown
        function ChooseImageDropDownValueChanged(app, event)
            img_idx = app.ChooseImageDropDown.Value;
            img1 = imread("../images/img44.jpg"); % Lena
            img2 = imread("../images/img46.jpg"); % Lab
            img3 = imread("../images/img69.jpg"); % Lena2
            img4 = imread("../images/img70.jpg"); % Building
            src = img1;
            if img_idx == 2
                src = img2;
            elseif img_idx == 3
                src = img3;
            elseif img_idx == 4
                src = img4;
            end
            app.Image_11.ImageSource = src;
            [denoised_img, freq_img] = denoise(img_idx, 5);
            app.Image_12.ImageSource = double(denoised_img);
            app.Image_13.ImageSource = double(freq_img);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Tugas Kecil 2 IF4073 - Naufal Izza - Habibina Arif Muzayyan';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConvolutionTab = uitab(app.TabGroup);
            app.ConvolutionTab.Title = 'Convolution';
            app.ChooseImageButton = uibutton(app.ConvolutionTab, 'push');
            app.ChooseImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseImageButtonPushed, true);
            app.ChooseImageButton.Text = 'Choose Image';
            app.Image = uiimage(app.ConvolutionTab);
            app.SourceImageLabel_2 = uilabel(app.ConvolutionTab);
            app.SourceImageLabel_2.Text = 'Source Image';
            app.Image_2 = uiimage(app.ConvolutionTab);
            app.ConvolutedImageLabel = uilabel(app.ConvolutionTab);
            app.ConvolutedImageLabel.Text = 'Convoluted Image';
            app.Image_3 = uiimage(app.ConvolutionTab);
            app.UsingBuiltinconvLabel = uilabel(app.ConvolutionTab);
            app.UsingBuiltinconvLabel.Text = 'Using Built-in conv';
            app.UkuranFilterSpinnerLabel = uilabel(app.ConvolutionTab);
            app.UkuranFilterSpinnerLabel.Text = 'Ukuran Filter';
            app.UkuranFilterSpinner = uispinner(app.ConvolutionTab);
            app.UkuranFilterSpinner.Step = 2;
            app.UkuranFilterSpinner.Limits = [3 Inf];
            app.UkuranFilterSpinner.ValueChangedFcn = createCallbackFcn(app, @UkuranFilterSpinnerValueChanged, true);
            app.UkuranFilterSpinner.Value = 3;
            app.ImageSmoothingTab = uitab(app.TabGroup);
            app.ImageSmoothingTab.Title = 'Image Smoothing';
            app.ChooseImageButton_3 = uibutton(app.ImageSmoothingTab, 'push');
            app.ChooseImageButton_3.ButtonPushedFcn = createCallbackFcn(app, @ChooseImageButton_3Pushed, true);
            app.ChooseImageButton_3.Text = 'Choose Image';
            app.Image_7 = uiimage(app.ImageSmoothingTab);
            app.SourceImageLabel_4 = uilabel(app.ImageSmoothingTab);
            app.SourceImageLabel_4.Text = 'Source Image';
            app.Image_8 = uiimage(app.ImageSmoothingTab);
            app.SmoothFilteredImageLabel = uilabel(app.ImageSmoothingTab);
            app.SmoothFilteredImageLabel.Text = 'Smooth Filtered Image';
            app.TipeFilterDropDownLabel = uilabel(app.ImageSmoothingTab);
            app.TipeFilterDropDownLabel.Text = 'Tipe Filter';
            app.TipeFilterDropDown = uidropdown(app.ImageSmoothingTab);
            app.TipeFilterDropDown.Items = {'Mean', 'Gaussian'};
            app.TipeFilterDropDown.Value = 'Mean';
            app.UkuranFilterSpinner_2Label = uilabel(app.ImageSmoothingTab);
            app.UkuranFilterSpinner_2Label.Text = 'Ukuran Filter';
            app.UkuranFilterSpinner_2 = uispinner(app.ImageSmoothingTab);
            app.UkuranFilterSpinner_2.Step = 2;
            app.UkuranFilterSpinner_2.Limits = [3 Inf];
            app.UkuranFilterSpinner_2.Value = 3;
            app.LowPassFilteringTab = uitab(app.TabGroup);
            app.LowPassFilteringTab.Title = 'Low Pass Filtering';
            app.ChooseImageButton_6 = uibutton(app.LowPassFilteringTab, 'push');
            app.ChooseImageButton_6.Text = 'Choose Image';
            app.Image_16 = uiimage(app.LowPassFilteringTab);
            app.SourceImageLabel_8 = uilabel(app.LowPassFilteringTab);
            app.SourceImageLabel_8.Text = 'Source Image';
            app.Image_17 = uiimage(app.LowPassFilteringTab);
            app.HighPassFilteredImageLabel_3 = uilabel(app.LowPassFilteringTab);
            app.HighPassFilteredImageLabel_3.Text = 'High Pass Filtered Image';
            app.TipeFilterDropDown_3Label = uilabel(app.LowPassFilteringTab);
            app.TipeFilterDropDown_3Label.Text = 'Tipe Filter';
            app.TipeFilterDropDown_3 = uidropdown(app.LowPassFilteringTab);
            app.TipeFilterDropDown_3.Items = {'IHPF', 'GHPF', 'BHPF'};
            app.TipeFilterDropDown_3.Value = 'IHPF';
            app.HighPassFilteringTab_2 = uitab(app.TabGroup);
            app.HighPassFilteringTab_2.Title = 'High Pass Filtering';
            app.ChooseImageButton_5 = uibutton(app.HighPassFilteringTab_2, 'push');
            app.ChooseImageButton_5.Text = 'Choose Image';
            app.Image_14 = uiimage(app.HighPassFilteringTab_2);
            app.SourceImageLabel_7 = uilabel(app.HighPassFilteringTab_2);
            app.SourceImageLabel_7.Text = 'Source Image';
            app.Image_15 = uiimage(app.HighPassFilteringTab_2);
            app.HighPassFilteredImageLabel_2 = uilabel(app.HighPassFilteringTab_2);
            app.HighPassFilteredImageLabel_2.Text = 'High Pass Filtered Image';
            app.TipeFilterDropDown_2Label = uilabel(app.HighPassFilteringTab_2);
            app.TipeFilterDropDown_2Label.Text = 'Tipe Filter';
            app.TipeFilterDropDown_2 = uidropdown(app.HighPassFilteringTab_2);
            app.TipeFilterDropDown_2.Items = {'IHPF', 'GHPF', 'BHPF'};
            app.TipeFilterDropDown_2.Value = 'IHPF';
            app.BrigthenTab = uitab(app.TabGroup);
            app.BrigthenTab.Title = 'Brigthen';
            app.ChooseImageButton_4 = uibutton(app.BrigthenTab, 'push');
            app.ChooseImageButton_4.ButtonPushedFcn = createCallbackFcn(app, @ChooseImageButton_4Pushed, true);
            app.ChooseImageButton_4.Text = 'Choose Image';
            app.Image_9 = uiimage(app.BrigthenTab);
            app.SourceImageLabel_5 = uilabel(app.BrigthenTab);
            app.SourceImageLabel_5.Text = 'Source Image';
            app.Image_10 = uiimage(app.BrigthenTab);
            app.BrightenedImageLabel = uilabel(app.BrigthenTab);
            app.BrightenedImageLabel.Text = 'Brightened Image';
            app.BrightnessSliderLabel = uilabel(app.BrigthenTab);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.BrigthenTab);
            app.BrightnessSlider.Limits = [0 5];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.BrightnessSlider.Value = 2;
            app.MedianFilterTab = uitab(app.TabGroup);
            app.MedianFilterTab.Title = 'Median Filter';
            app.ChooseImageButton_2 = uibutton(app.MedianFilterTab, 'push');
            app.ChooseImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @ChooseImageButton_2Pushed, true);
            app.ChooseImageButton_2.Text = 'Choose Image';
            app.Image_4 = uiimage(app.MedianFilterTab);
            app.SourceImageLabel_3 = uilabel(app.MedianFilterTab);
            app.SourceImageLabel_3.Text = 'Source Image';
            app.Image_5 = uiimage(app.MedianFilterTab);
            app.SaltandPepperNoisyImageLabel = uilabel(app.MedianFilterTab);
            app.SaltandPepperNoisyImageLabel.Text = 'Salt and Pepper Noisy Image';
            app.Image_6 = uiimage(app.MedianFilterTab);
            app.MedianFilteredImageLabel = uilabel(app.MedianFilterTab);
            app.MedianFilteredImageLabel.Text = 'Median Filtered Image';
            app.PeriodicDenoiseTab = uitab(app.TabGroup);
            app.PeriodicDenoiseTab.Title = 'Periodic Denoise';
            app.Image_11 = uiimage(app.PeriodicDenoiseTab);
            app.SourceImageLabel_6 = uilabel(app.PeriodicDenoiseTab);
            app.SourceImageLabel_6.Text = 'Source Image';
            app.Image_12 = uiimage(app.PeriodicDenoiseTab);
            app.PeriodicDenoisedImageLabel = uilabel(app.PeriodicDenoiseTab);
            app.PeriodicDenoisedImageLabel.Text = 'Periodic Denoised Image';
            app.Image_13 = uiimage(app.PeriodicDenoiseTab);
            app.FilterResultinFrequencySpaceLabel = uilabel(app.PeriodicDenoiseTab);
            app.FilterResultinFrequencySpaceLabel.Text = 'Filter Result in Frequency Space';
            app.ChooseImageDropDownLabel = uilabel(app.PeriodicDenoiseTab);
            app.ChooseImageDropDownLabel.Text = 'Choose Image';
            app.ChooseImageDropDown = uidropdown(app.PeriodicDenoiseTab);
            app.ChooseImageDropDown.Items = {'1', '2', '3', '4'};
            app.ChooseImageDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseImageDropDownValueChanged, true);
            app.ChooseImageDropDown.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tugas_Kecil_2_IF4073

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end