classdef Tugas_Kecil_3_IF4073 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        EdgeDetectionTab             matlab.ui.container.Tab
        KonstantaSpinner             matlab.ui.control.Spinner
        KonstantaSpinnerLabel        matlab.ui.control.Label
        ObjectSegmentationLabel      matlab.ui.control.Label
        Image_3                      matlab.ui.control.Image
        OperatordeteksitepiDropDown  matlab.ui.control.DropDown
        OperatordeteksitepiDropDownLabel  matlab.ui.control.Label
        EdgeImageLabel               matlab.ui.control.Label
        Image_2                      matlab.ui.control.Image
        SourceImageLabel             matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        ChooseImageButton            matlab.ui.control.Button
    end

    
    properties (Access = public)
        dir =  '../img'; % Image Directory
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseImageButton
        function ChooseImageButtonPushed(app, event)
            [file,~] = uigetfile({'*.*';'*.png';'*.jpg'; '*.jpeg';},'Choose image',app.dir);
            src = fullfile(app.dir,file);
            app.Image.ImageSource = src;
            I = imread(src);
            value = app.OperatordeteksitepiDropDown.Value;
            if strcmp(value,'LoG')
                edgeimg = lapofgauss(I);
            elseif strcmp(value,'Sobel')
                c = app.KonstantaSpinner.Value;
                edgeimg = sobel(I, c);
            elseif strcmp(value,'Prewitt')
                edgeimg = prewitt(I);
            elseif strcmp(value,'Roberts')
                edgeimg = roberts(I);
            elseif strcmp(value,'Canny')
                edgeimg = canny(I);
            else
                edgeimg = laplace(I,'normal');
            end
            segmentedimg = segmentation(edgeimg, I, value);
            app.Image_2.ImageSource = edgeimg;
            app.Image_3.ImageSource = segmentedimg;
        end

        % Value changed function: OperatordeteksitepiDropDown
        function OperatordeteksitepiDropDownValueChanged(app, event)
            value = app.OperatordeteksitepiDropDown.Value;
            src = app.Image.ImageSource;
            if (src ~= "")
                I = imread(src);
                if strcmp(value,'LoG')
                    edgeimg = lapofgauss(I);
                elseif strcmp(value,'Sobel')
                    c = app.KonstantaSpinner.Value;
                    edgeimg = sobel(I, c);
                elseif strcmp(value,'Prewitt')
                    edgeimg = prewitt(I);
                elseif strcmp(value,'Roberts')
                    edgeimg = roberts(I);
                elseif strcmp(value,'Canny')
                    edgeimg = canny(I);
                else
                    edgeimg = laplace(I,'normal');
                end
                segmentedimg = segmentation(edgeimg, I, value);
                app.Image_2.ImageSource = edgeimg;
                app.Image_3.ImageSource = segmentedimg;
            end
        end

        % Value changed function: KonstantaSpinner
        function KonstantaSpinnerValueChanged(app, event)
            value = app.OperatordeteksitepiDropDown.Value;
            src = app.Image.ImageSource;
            if (src ~= "")
                I = imread(src);
                if strcmp(value,'LoG')
                    edgeimg = lapofgauss(I);
                elseif strcmp(value,'Sobel')
                    c = app.KonstantaSpinner.Value;
                    edgeimg = sobel(I, c);
                elseif strcmp(value,'Prewitt')
                    edgeimg = prewitt(I);
                elseif strcmp(value,'Roberts')
                    edgeimg = roberts(I);
                elseif strcmp(value,'Canny')
                    edgeimg = canny(I);
                else
                    edgeimg = laplace(I,'normal');
                end
                segmentedimg = segmentation(edgeimg, I, value);
                app.Image_2.ImageSource = edgeimg;
                app.Image_3.ImageSource = segmentedimg;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Tugas Kecil 3 IF4073 - Habibina Arif Muzayyan - Naufal Yahya Kurnianto';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EdgeDetectionTab = uitab(app.TabGroup);
            app.EdgeDetectionTab.Title = 'Edge Detection';
            app.ChooseImageButton = uibutton(app.EdgeDetectionTab, 'push');
            app.ChooseImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseImageButtonPushed, true);
            app.ChooseImageButton.Text = 'Choose Image';
            app.Image = uiimage(app.EdgeDetectionTab);
            app.SourceImageLabel = uilabel(app.EdgeDetectionTab);
            app.SourceImageLabel.Text = 'Source Image';
            app.Image_2 = uiimage(app.EdgeDetectionTab);
            app.EdgeImageLabel = uilabel(app.EdgeDetectionTab);
            app.EdgeImageLabel.Text = 'Edge Image';
            app.OperatordeteksitepiDropDownLabel = uilabel(app.EdgeDetectionTab);
            app.OperatordeteksitepiDropDownLabel.Text = 'Operator deteksi tepi';
            app.OperatordeteksitepiDropDown = uidropdown(app.EdgeDetectionTab);
            app.OperatordeteksitepiDropDown.Items = {'Laplace', 'LoG', 'Sobel', 'Prewitt', 'Roberts', 'Canny'};
            app.OperatordeteksitepiDropDown.ValueChangedFcn = createCallbackFcn(app, @OperatordeteksitepiDropDownValueChanged, true);
            app.OperatordeteksitepiDropDown.Value = 'Laplace';
            app.Image_3 = uiimage(app.EdgeDetectionTab);
            app.ObjectSegmentationLabel = uilabel(app.EdgeDetectionTab);
            app.ObjectSegmentationLabel.Text = 'Object Segmentation';
            app.KonstantaSpinnerLabel = uilabel(app.EdgeDetectionTab);
            app.KonstantaSpinnerLabel.Text = 'Konstanta';
            app.KonstantaSpinner = uispinner(app.EdgeDetectionTab);
            app.KonstantaSpinner.ValueChangedFcn = createCallbackFcn(app, @KonstantaSpinnerValueChanged, true);
            app.KonstantaSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tugas_Kecil_3_IF4073

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end